classdef BagheriBinaryStereo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BinaryStereoMatchingPanel      matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        MethodSelectionDropDownLabel   matlab.ui.control.Label
        MethodSelectionDropDown        matlab.ui.control.DropDown
        DisparityEditFieldLabel        matlab.ui.control.Label
        DisparityEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel       matlab.ui.control.Label
        WindowSizeEditField            matlab.ui.control.NumericEditField
        ImageDropDownLabel             matlab.ui.control.Label
        ImageDropDown                  matlab.ui.control.DropDown
        PostProcessingFilterCheckBox   matlab.ui.control.CheckBox
        FilterWindowEditFieldLabel     matlab.ui.control.Label
        FilterWindowEditField          matlab.ui.control.NumericEditField
        nBitEditFieldLabel             matlab.ui.control.Label
        nBitEditField                  matlab.ui.control.NumericEditField
        nBitmustbelessthanWindowSizeLabel  matlab.ui.control.Label
        ElapsedTimeEditFieldLabel      matlab.ui.control.Label
        ElapsedTimeEditField           matlab.ui.control.NumericEditField
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        ErrorPercentEditFieldLabel     matlab.ui.control.Label
        ErrorPercentEditField          matlab.ui.control.NumericEditField
        UIAxes5                        matlab.ui.control.UIAxes
        StateEditFieldLabel            matlab.ui.control.Label
        StateEditField                 matlab.ui.control.EditField
        UIAxes5_2                      matlab.ui.control.UIAxes
        UIAxes5_3                      matlab.ui.control.UIAxes
        AllErrorPercentEditFieldLabel  matlab.ui.control.Label
        AllErrorPercentEditField       matlab.ui.control.NumericEditField
        ERTEditFieldLabel              matlab.ui.control.Label
        ERTEditField                   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            warning off
            
            GR=getappdata(app.UIAxes5,'GR');
            if isempty(GR)
                leftg=imread('Teddy_left.png');
                imshow(leftg,'Parent',app.UIAxes_2)
                scale=4;
                leftg=rgb2gray(leftg);
                setappdata(app.UIAxes_2,'leftImg',leftg);
                rightg=imread('Teddy_right.png');
                imshow(rightg,'Parent',app.UIAxes_3)
                setappdata(app.UIAxes_3,'rightImg',rightg);
                GR=imread('Teddy_left_groundtruth.png');
                GR=double(GR);
                %                 GR=GR./scale;
                imshow(GR,[0 max(max(GR))],'Parent',app.UIAxes5)
                nonocc=imread('Teddy_nonocc.png');
                nonocc=double(nonocc);
                imshow(nonocc,'Parent',app.UIAxes5_2)
                setappdata(app.UIAxes5_2,'nonocc',nonocc);
                setappdata(app.UIAxes5,'GR',GR);
                setappdata(app.UIAxes5_3,'scale',scale);
            end
            GR=getappdata(app.UIAxes5,'GR'); 
            nonocc=getappdata(app.UIAxes5_2,'nonocc');
            
            nonocc=double(nonocc);
            leftg=getappdata(app.UIAxes_2,'leftImg');
            rightg=getappdata(app.UIAxes_3,'rightImg');
            scale=getappdata(app.UIAxes5_3,'scale');
            Method = app.MethodSelectionDropDown.Value;
            disparity = app.DisparityEditField.Value;
            WS=app.WindowSizeEditField.Value;
            filt = app.PostProcessingFilterCheckBox.Value;
            filtWindow= app.FilterWindowEditField.Value;
            app.StateEditField.Value='Processing'
            %             app.nBitEditField.Enable='off';
            MSG=msgbox('Wait until process finishes');
            tic
            if Method=='LBP                  '
                %                 app.nBitEditField.Enable='off';
                [dispMap,gr,error]=lbpFunc(leftg,rightg,WS,disparity,filt,filtWindow,GR,scale);
                minindex=min(min(dispMap));
                t=0;
                
            else
                if Method=='CENSUS-Sparse        '
                    %                     app.nBitEditField.Enable='off';
                    [dispMap,gr,error]=censparFunc(leftg,rightg,WS,disparity,filt,filtWindow,scale);
                    minindex=min(min(dispMap));
                else
                    if Method=='CENSUS-Dense         '
                        %                         app.nBitEditField.Enable='off';
                        app.WindowSizeEditField.Value=6;
                        [dispMap,gr,error]=censdensFunc(leftg,rightg,WS,disparity,filt,filtWindow,GR,scale);
                        minindex=min(min(dispMap));
                        %                         app.nBitEditField.Enable='off';
                    else
                        if Method=='BRIEF                '
                            app.nBitEditField.Enable='on';
                            nbit=app.nBitEditField.Value;
                            if nbit<WS
                                [dispMap,gr,error]=briefFunc(leftg,rightg,WS,disparity,filt,filtWindow,nbit,GR,scale);
                                minindex=min(min(dispMap));
                            else
                                msgbox('nBit must be less than Window Size')
                            end
                            
                        else
                            if Method=='LBP_alpha            '
                                app.nBitEditField.Enable='on';
                                nbit=app.nBitEditField.Value;
                                if nbit<WS
                                    [dispMap,gr,error]=lbpalphaFunc(leftg,rightg,WS,disparity,filt,filtWindow,nbit,GR,scale);
                                    minindex=min(min(dispMap));
                                else
                                    msgbox('nBit must be less than Window Size')
                                end
                            else
                                if Method=='Hybrid BRIEF-LBPalpha'
                                    app.nBitEditField.Enable='on';
                                    nbit=app.nBitEditField.Value;
                                    if nbit<WS
                                        [dispMap,gr,error]=lbpAlpha_BRIEF_Func(leftg,rightg,WS,disparity,filt,filtWindow,nbit,GR,scale);
                                        minindex=min(min(dispMap));
                                    else
                                        msgbox('nBit must be less than Window Size')
                                    end
                                end
                            end
                        end
                    end
                end
            end
            %             dispMap=double(dispMap);
            %              dispMap=dispMap./scale;
            time =  toc;
            delete(MSG)
            app.StateEditField.Value='-------'
            mystring = int2str( time );
            % set(text12, 'String', mystring);
            app.ElapsedTimeEditField.Value=time;
            %             figure(1)
            time=0;
            %             imshow(dispmap, [0 disparity]);
            
            cc=0;
            ee=0;
            
            for i=1:size(dispMap,1)
                for j=1:size(dispMap,2)
                    if  nonocc(i,j)~=0
                        cc=cc+1;
                        error(i,j)=(gr(i,j)/scale-dispMap(i,j));
                        if error(i,j)>1
                            ee=ee+1;
                            errMap(i,j)=255;
                        end
                    end
                end
            end
            cc1=0;
            ee1=0;
            for i=1:size(dispMap,1)
                for j=1:size(dispMap,2)
                    if  nonocc(i,j)~=0 && gr(i,j)>0
                        cc1=cc1+1;
                        
                        if (error(i,j))>1
                            ee1=ee1+1;
                          
                        end
                    end
                end
            end
            errorPercent=ee/cc;
            errorPercentALL=ee1/cc1;
            imshow(dispMap,[0 disparity],'Parent',app.UIAxes_4)
            imshow(errMap,'Parent',app.UIAxes5_3)
            app.ErrorPercentEditField.Value=100*errorPercent;
            app.AllErrorPercentEditField.Value=100*errorPercentALL;
            imgc = imresize(leftg,[size(dispMap,1) size(dispMap,2)]);
            for i2 = 1 : size(dispMap,1)
                for j2 = 1 : size(dispMap,2)
                    if((j2-minindex-1) > 0)
                        if (dispMap(i2,j2) == dispMap(i2,j2-minindex-1))
                            tot(i2,j2) =dispMap(i2,j2);
                        else
                            tot(i2,j2) = 255;
                         
                        end
                    end
                    
                end
            end
            tot = uint8(tot);
            erm = imgc - tot;
            cnt=0;
            for i3 = 1 : size(dispMap,1)
                for j3 = 1 : size(dispMap,2)
                    if (abs(erm(i3,j3)) > 1)
                        cnt = cnt + 1;
                    end
                end
            end

            app.ERTEditField.Value= cnt / (size(dispMap,1) * size(dispMap,2));
          
        end

        % Callback function
        function ReadImageLeftButtonValueChanged(app, event)
            
            
        end

        % Callback function
        function ReadImageRightButtonValueChanged(app, event)
            
        end

        % Value changed function: MethodSelectionDropDown
        function MethodSelectionDropDownValueChanged(app, event)
            Method = app.MethodSelectionDropDown.Value;
            if Method=='LBP_alpha            '
                app.nBitEditField.Enable='on';
            else
                if Method=='BRIEF                '
                    app.nBitEditField.Enable='on';
                else
                    if Method=='Hybrid BRIEF-LBPalpha'
                        app.nBitEditField.Enable='on';
                    else
                        app.nBitEditField.Enable='off';
                    end
                end
            end
        end

        % Value changed function: DisparityEditField
        function DisparityEditFieldValueChanged(app, event)
            value = app.DisparityEditField.Value;
            
        end

        % Value changed function: ElapsedTimeEditField
        function ElapsedTimeEditFieldValueChanged(app, event)
            value = app.ElapsedTimeEditField.Value;
            
        end

        % Value changed function: ErrorPercentEditField
        function ErrorPercentEditFieldValueChanged(app, event)
            value = app.ErrorPercentEditField.Value;
            
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            value = app.ImageDropDown.Value;
            %             app.nBitEditField.Enable='off';
            if value=='teddy  '
                leftg=imread('Teddy_left.png');
                imshow(leftg,'Parent',app.UIAxes_2)
                scale=4;
                leftg=rgb2gray(leftg);
                setappdata(app.UIAxes_2,'leftImg',leftg);
                rightg=imread('Teddy_right.png');
                imshow(rightg,'Parent',app.UIAxes_3)
                app.DisparityEditField.Value=60;
                rightg=rgb2gray(rightg);
                setappdata(app.UIAxes_3,'rightImg',rightg);
                
                GR=imread('Teddy_left_groundtruth.png');
                GR=double(GR);
                %                 GR=GR./scale;
                imshow(GR,[0 max(max(GR))],'Parent',app.UIAxes5)
                nonocc=imread('Teddy_nonocc.png');
                nonocc=double(nonocc);
                imshow(nonocc,'Parent',app.UIAxes5_2)
                setappdata(app.UIAxes5_2,'nonocc',nonocc);
                setappdata(app.UIAxes5,'GR',GR);
            else
                if value=='tsukuba'
                    app.DisparityEditField.Value=16;
                    leftg=imread('Tsukuba_left.png');
                    imshow(leftg,'Parent',app.UIAxes_2)
                    leftg=rgb2gray(leftg);
                    setappdata(app.UIAxes_2,'leftImg',leftg);
                    rightg=imread('Tsukuba_right.png');
                    imshow(rightg,'Parent',app.UIAxes_3)
                    rightg=rgb2gray(rightg);
                    nonocc=imread('Tsukuba_nonocc.png');
                    nonocc=double(nonocc);
                    imshow(nonocc,'Parent',app.UIAxes5_2)
                    setappdata(app.UIAxes5_2,'nonocc',nonocc);
                    setappdata(app.UIAxes_3,'rightImg',rightg);
                    GR=imread('Tsukuba_gt3.jpg');
                    
                    GR=rgb2gray(GR);
                    
                    scale=16;
                    %                     GR=GR./scale;
                    imshow(GR,[0 max(max(GR))],'Parent',app.UIAxes5)
                    setappdata(app.UIAxes5,'GR',GR);
                else
                    if value=='cones  '
                        leftg=imread('cones_left.png');
                        imshow(leftg,'Parent',app.UIAxes_2)
                        leftg=rgb2gray(leftg);
                        setappdata(app.UIAxes_2,'leftImg',leftg);
                        rightg=imread('cones_right.png');
                        imshow(rightg,'Parent',app.UIAxes_3)
                        rightg=rgb2gray(rightg);
                        setappdata(app.UIAxes_3,'rightImg',rightg);
                        nonocc=imread('cones_nonocc.png');
                        nonocc=double(nonocc);
                        imshow(nonocc,'Parent',app.UIAxes5_2)
                        setappdata(app.UIAxes5_2,'nonocc',nonocc);
                        GR=imread('cones_left_groundtruth.png');
                        GR=double(GR);
                        scale=4;
                        %                         GR=GR./scale;
                        imshow(GR,[0 max(max(GR))],'Parent',app.UIAxes5)
                        setappdata(app.UIAxes5,'GR',GR);
                        app.DisparityEditField.Value=60;
                    else
                        
                        leftg=imread('venus_left.png');
                        imshow(leftg,'Parent',app.UIAxes_2)
                        leftg=rgb2gray(leftg);
                        setappdata(app.UIAxes_2,'leftImg',leftg);
                        rightg=imread('venus_right.png');
                        imshow(rightg,'Parent',app.UIAxes_3)
                        rightg=rgb2gray(rightg);
                        setappdata(app.UIAxes_3,'rightImg',rightg);
                        nonocc=imread('venus_nonocc.png');
                        nonocc=double(nonocc);
                        imshow(nonocc,'Parent',app.UIAxes5_2)
                        setappdata(app.UIAxes5_2,'nonocc',nonocc);
                        GR=imread('venus_left_groundtruth.png');
                        GR=double(GR);
                        scale=8;
                        %                         GR=GR./scale;
                        imshow(GR,[0 max(max(GR))],'Parent',app.UIAxes5)
                        setappdata(app.UIAxes5,'GR',GR);
                        app.DisparityEditField.Value=20;
                    end
                end
            end
            setappdata(app.UIAxes5_3,'scale',scale);
            value = app.ImageDropDown.Value;
            %             app.nBitEditField.Enable='off';
            
        end

        % Value changed function: PostProcessingFilterCheckBox
        function PostProcessingFilterCheckBoxValueChanged(app, event)
            value = app.PostProcessingFilterCheckBox.Value;
            if value
                app.FilterWindowEditField.Enable='on'
            else
                app.FilterWindowEditField.Enable='off'
            end
        end

        % Value changed function: FilterWindowEditField
        function FilterWindowEditFieldValueChanged(app, event)
            value = app.FilterWindowEditField.Value;
            
        end

        % Value changed function: nBitEditField
        function nBitEditFieldValueChanged(app, event)
            value = app.nBitEditField.Value;
            
        end

        % Value changed function: ERTEditField
        function ERTEditFieldValueChanged(app, event)
            value = app.ERTEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BinaryStereoMatchingPanel = uipanel(app.UIFigure);
            app.BinaryStereoMatchingPanel.Title = 'Binary Stereo Matching';
            app.StartButton = uibutton(app.BinaryStereoMatchingPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MethodSelectionDropDownLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.MethodSelectionDropDownLabel.Text = 'Method Selection';
            app.MethodSelectionDropDown = uidropdown(app.BinaryStereoMatchingPanel);
            app.MethodSelectionDropDown.Items = {'LBP                  ', 'BRIEF                ', 'CENSUS-Dense         ', 'LBP_alpha            ', 'Hybrid BRIEF-LBPalpha'};
            app.MethodSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodSelectionDropDownValueChanged, true);
            app.MethodSelectionDropDown.Value = 'LBP                  ';
            app.DisparityEditFieldLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.DisparityEditFieldLabel.Text = 'Disparity';
            app.DisparityEditField = uieditfield(app.BinaryStereoMatchingPanel, 'numeric');
            app.DisparityEditField.Limits = [0 Inf];
            app.DisparityEditField.ValueChangedFcn = createCallbackFcn(app, @DisparityEditFieldValueChanged, true);
            app.DisparityEditField.Value = 73;
            app.WindowSizeEditFieldLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.BinaryStereoMatchingPanel, 'numeric');
            app.WindowSizeEditField.Limits = [0 Inf];
            app.WindowSizeEditField.Value = 32;
            app.ImageDropDownLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.BinaryStereoMatchingPanel);
            app.ImageDropDown.Items = {'teddy  ', 'tsukuba', 'cones  ', 'venus  '};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = 'teddy  ';
            app.PostProcessingFilterCheckBox = uicheckbox(app.BinaryStereoMatchingPanel);
            app.PostProcessingFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @PostProcessingFilterCheckBoxValueChanged, true);
            app.PostProcessingFilterCheckBox.Text = 'Post Processing Filter';
            app.FilterWindowEditFieldLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.FilterWindowEditFieldLabel.Enable = 'off';
            app.FilterWindowEditFieldLabel.Text = 'Filter Window';
            app.FilterWindowEditField = uieditfield(app.BinaryStereoMatchingPanel, 'numeric');
            app.FilterWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterWindowEditFieldValueChanged, true);
            app.FilterWindowEditField.Enable = 'off';
            app.FilterWindowEditField.Value = 16;
            app.nBitEditFieldLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.nBitEditFieldLabel.Enable = 'off';
            app.nBitEditFieldLabel.Text = 'nBit';
            app.nBitEditField = uieditfield(app.BinaryStereoMatchingPanel, 'numeric');
            app.nBitEditField.Limits = [0 Inf];
            app.nBitEditField.ValueChangedFcn = createCallbackFcn(app, @nBitEditFieldValueChanged, true);
            app.nBitEditField.Enable = 'off';
            app.nBitEditField.Value = 15;
            app.nBitmustbelessthanWindowSizeLabel = uilabel(app.BinaryStereoMatchingPanel);
            app.nBitmustbelessthanWindowSizeLabel.Text = 'nBit must be less than Window Size';
            app.ElapsedTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElapsedTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ElapsedTimeEditFieldValueChanged, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Left Image')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Right Image')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Stereo Matched Image')
            app.ErrorPercentEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorPercentEditFieldLabel.Text = 'Error Percent%';
            app.ErrorPercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorPercentEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorPercentEditFieldValueChanged, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Ground Truth')
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            app.StateEditField.Value = '           -----';
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            title(app.UIAxes5_2, 'nonocc')
            app.UIAxes5_3 = uiaxes(app.UIFigure);
            title(app.UIAxes5_3, 'Error Map')
            app.AllErrorPercentEditFieldLabel = uilabel(app.UIFigure);
            app.AllErrorPercentEditFieldLabel.Text = 'All Error Percent%';
            app.AllErrorPercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ERTEditFieldLabel = uilabel(app.UIFigure);
            app.ERTEditFieldLabel.Text = 'ERT';
            app.ERTEditField = uieditfield(app.UIFigure, 'numeric');
            app.ERTEditField.ValueChangedFcn = createCallbackFcn(app, @ERTEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BagheriBinaryStereo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end